classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EstadoLabel            matlab.ui.control.Label
        NotacercanaLabel       matlab.ui.control.Label
        FrecuenciaActualLabel  matlab.ui.control.Label
        b1                     matlab.ui.control.Button
        axes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b1
        function b1Pushed(app, event)
            fs = 44100;
            recObj = audiorecorder(fs, 16, 1);
            recordblocking(recObj, 1.7);
            y = getaudiodata(recObj);
            dp = 0.1;
            f = 1000;
            Wp=f/(fs/2);
            [b, a] = cheby1(4,dp,Wp,'low');
            y1= filter(b,a,y);
            g=fft(y1);
            potencia=abs(g).^2;
            plot(app.axes,potencia)
            df=1/(length(y)/fs);
            f=(0:length(y)-1)*df; 
            [pks,locs]=findpeaks(potencia,"SortStr","descend","NPeaks",7);
            pk = min(f(locs));
            pk1 = string(pk);
            app.FrecuenciaActualLabel.Text = "Frecuencia Actual: " + pk1;
            order1 = 30 + 12 * log2(pk/440);
            order2 = round(order1);
            notas1 = ["E2", "F2", "F#2", "G2", "G#2", ...
                "A2", "A#2", "B2", "C3", "C#3", "D3", "D#3","E3", "F3", ...
                "F#3", "G3","G#3", ...
                "A3", "A#3", "B3", "C4", "C#4", "D4", "D#4","E4", "F4", ...
                "F#4", "G4", "G#4", ...
                "A4", "A#4", "B4", "C5", "C#5", "D5", "D#5","E5", "F5", ...
                "F#5", "G5", "G#5", ...
                "A5", "A#5", "B5", "C6"];
            notas2 = ["mi1", "fa1", "fa#1", "sol1", "sol#1", ...
                "la1", "la#1", "si1", "do2", "do#2", "re2", "re#2","mi2",...
                "fa2", "fa#2", "sol2","sol#2", ...
                "la2", "la#3", "si3", "do3", "do#3", "re3", "re#3","mi3",...
                "fa3", "fa#3", "sol3","sol#3", ...
                "la3", "la#3", "si3", "do4", "do#4", "re4", "re#4","mi4",...
                "fa4", "fa#4", "sol4","sol#4", ...
                "la4", "la#4", "si4", "do5"];
            if order2 < 46
                app.NotacercanaLabel.Text = "Nota cercana : " + ...
                    notas1(order2) + " / " + notas2(order2);
                n = order2 - order1;
                if n>0.35 
                    app.EstadoLabel.Text = "Estado: " +  "Por encima de la frecuencia";
                elseif n < -0.35
                    app.EstadoLabel.Text = "Estado: " +  "Por debajo de la frecuencia";
                else
                    app.EstadoLabel.Text = "Estado: " +  "En rango";
                end
            else
                app.EstadoLabel.Text = "Estado: " +  "Intente de nuevo";
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Transformada de Fourier')
            xlabel(app.axes, 'Frecuencia')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.axes.XLim = [0 700];
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1Pushed, true);
            app.b1.Tag = 'b1';
            app.b1.Text = 'Precione y emita la nota';
            app.FrecuenciaActualLabel = uilabel(app.UIFigure);
            app.FrecuenciaActualLabel.Text = 'Frecuencia:Actual:';
            app.NotacercanaLabel = uilabel(app.UIFigure);
            app.NotacercanaLabel.Text = 'Nota cercana :';
            app.EstadoLabel = uilabel(app.UIFigure);
            app.EstadoLabel.Text = 'Estado: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end